classdef pieceAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisorBieleUIFigure  matlab.ui.Figure
        Toolbar             matlab.ui.container.Toolbar
        bBoxTool            matlab.ui.container.toolbar.PushTool
        appLayout           matlab.ui.container.GridLayout
        settingsLayout      matlab.ui.container.GridLayout
        resultsGroup        matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        canva               matlab.ui.control.UIAxes
        configTabs          matlab.ui.container.TabGroup
        loadFiles           matlab.ui.container.Tab
        loadFilesLayout     matlab.ui.container.GridLayout
        loadImageButton     matlab.ui.control.Button
        previewImage        matlab.ui.control.Image
        loadSVGButton       matlab.ui.control.Button
        previewSVG          matlab.ui.control.Image
    end

 
    properties (Access = private)
        appWrapper;

        modelImage;
        modelSVG;

        imageController;
        svgController;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Wrapper view
            app.appWrapper = gui.ViewWrapper(app.canva, ...
                app.previewImage, ...
                app.previewSVG);

            % Create models
            app.modelImage = models.ImageModel();
            app.modelSVG   = models.SVGModel();
    
            % Create controllers
            app.imageController = controllers.ImageController(app.modelImage, ...
                app.previewImage, ...
                app.appWrapper);
            app.svgController = controllers.SVGController(app.modelSVG, ...
                app.previewSVG, ...
                app.appWrapper);
        end

        % Button pushed function: loadImageButton
        function loadImageButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;', ...
                'Imágenes (*.png, *.jpg, *.jpeg)'}, ...
                'Selecciona una imagen');

            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.imageController.loadImageFromDialog(path, file);
        end

        % Image clicked function: previewImage
        function previewImageClicked(app, event)
            app.imageController.previewImageOnCanva();
        end

        % Button pushed function: loadSVGButton
        function loadSVGButtonPushed(app, event)
            % Allowed files
            [file, path] = uigetfile({'*.svg', 'Archivos SVG (*.svg)'}, ...
                                                 'Selecciona un archivo SVG');
            % Not selected file
            if isequal(file, 0)
                return;
            end
            
            app.svgController.loadSVGFromDialog(path, file);
        end

        % Image clicked function: previewSVG
        function previewSVGClicked(app, event)
            app.svgController.previewSVGOnCanva();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.VisorBieleUIFigure = uifigure('Visible', 'off');
            app.VisorBieleUIFigure.Name = 'Visor Biele';
            app.VisorBieleUIFigure.Icon = fullfile(pathToMLAPP, 'picturesApp', 'logoBiele.png');
            app.Toolbar = uitoolbar(app.VisorBieleUIFigure);
            app.bBoxTool = uipushtool(app.Toolbar);
            app.bBoxTool.Tooltip = {'Dibujar BoundingBox sobre la pieza'};
            app.bBoxTool.Icon = fullfile(pathToMLAPP, 'picturesApp', 'bBoxToolIcon.png');
            app.appLayout = uigridlayout(app.VisorBieleUIFigure);
            app.configTabs = uitabgroup(app.appLayout);
            app.loadFiles = uitab(app.configTabs);
            app.loadFiles.Title = 'Botonera';
            app.loadFilesLayout = uigridlayout(app.loadFiles);
            app.previewSVG = uiimage(app.loadFilesLayout);
            app.previewSVG.ImageClickedFcn = createCallbackFcn(app, @previewSVGClicked, true);
            app.loadSVGButton = uibutton(app.loadFilesLayout, 'push');
            app.loadSVGButton.ButtonPushedFcn = createCallbackFcn(app, @loadSVGButtonPushed, true);
            app.loadSVGButton.Text = 'Cargar SVG';
            app.previewImage = uiimage(app.loadFilesLayout);
            app.previewImage.ImageClickedFcn = createCallbackFcn(app, @previewImageClicked, true);
            app.loadImageButton = uibutton(app.loadFilesLayout, 'push');
            app.loadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImageButtonPushed, true);
            app.loadImageButton.Text = 'Cargar imagen';
            app.settingsLayout = uigridlayout(app.appLayout);
            app.canva = uiaxes(app.settingsLayout);
            title(app.canva, 'Title')
            xlabel(app.canva, 'X')
            ylabel(app.canva, 'Y')
            zlabel(app.canva, 'Z')
            app.resultsGroup = uitabgroup(app.settingsLayout);
            app.Tab = uitab(app.resultsGroup);
            app.Tab.Title = 'Tab';
            % Show the figure after all components are created
            app.VisorBieleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pieceAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisorBieleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisorBieleUIFigure)
        end
    end
end